classdef snake < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        Panel                     matlab.ui.container.Panel
        AlphaLabel                matlab.ui.control.Label
        AlphaEditField            matlab.ui.control.EditField
        BetaLabel                 matlab.ui.control.Label
        BetaEditField             matlab.ui.control.EditField
        GammaLabel                matlab.ui.control.Label
        GammaEditField            matlab.ui.control.EditField
        IterationsEditFieldLabel  matlab.ui.control.Label
        IterationsEditField       matlab.ui.control.EditField
        NLabel                    matlab.ui.control.Label
        NEditField                matlab.ui.control.EditField
        Panel_2                   matlab.ui.container.Panel
        SnakeTypeDropDownLabel    matlab.ui.control.Label
        SnakeTypeDropDown         matlab.ui.control.DropDown
        StrartButton              matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        SnakesLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        N; % N
        P; % N
        mode; % N
        X; % N
        Y; % N
        iterations;
        alpha;
        beta;
        gamma;
        snake_plot;
        reset;
    end
    
    methods (Access = private)
        function generate_and_plot(app)
            app.mode = app.SnakeTypeDropDown.Value;
            [app.X,app.Y] = generate_data(app.mode,app.N);
            if  app.mode == "Open"
                app.snake_plot = plot(app.UIAxes,app.X,app.Y);
            else
                X = [app.X;app.X(1)]
                Y = [app.Y;app.Y(1)]
                app.snake_plot = plot(app.UIAxes, X ,Y);
            end
        end
        function build_P_matrix(app)
            A = a_matrix(app.N, app.alpha, app.beta, app.mode);
            app.P = inv(A+ app.gamma .* eye(app.N));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.iterations = str2num(app.IterationsEditField.Value);
            app.alpha = str2num(app.AlphaEditField.Value);
            app.beta = str2num(app.BetaEditField.Value);
            app.gamma = str2num(app.GammaEditField.Value);
            app.N = str2num(app.NEditField.Value);
            app.mode = app.SnakeTypeDropDown.Value;
            generate_and_plot(app)
            build_P_matrix(app)
        end

        % Value changed function: SnakeTypeDropDown
        function SnakeTypeDropDownValueChanged(app, event)
            app.reset = true;
            generate_and_plot(app)
            build_P_matrix(app)
        end

        % Button pushed function: StrartButton
        function StrartButtonPushed(app, event)
            app.reset = false;
            extFx = 0;
            extFy = 0;
            for ii = 1:app.iterations
                if(app.reset)
                    app.reset = false;
                    break;
                end
                [app.X,app.Y] = update_position(app.X,app.Y,app.P,app.gamma,extFx,extFy);
                pause(0.1);
                if app.mode == "Open"
                  app.snake_plot.XData = [app.X];
                  app.snake_plot.YData = [app.Y];
                else
                  app.snake_plot.XData = [app.X;app.X(1)];
                  app.snake_plot.YData = [app.Y;app.Y(1)];
                end
                %frame = getframe(gcf);
                %writeVideo(v,frame);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reset = true;
            generate_and_plot(app)
            build_P_matrix(app)
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            app.alpha = str2num(event.Value);
        end

        % Value changing function: BetaEditField
        function BetaEditFieldValueChanging(app, event)
            app.beta = str2num(event.Value);  
        end

        % Value changing function: GammaEditField
        function GammaEditFieldValueChanging(app, event)
            app.gamma = str2num(event.Value);
        end

        % Value changing function: IterationsEditField
        function IterationsEditFieldValueChanging(app, event)
            app.iterations = str2num(event.Value);
        end

        % Value changing function: NEditField
        function NEditFieldValueChanging(app, event)
            app.N = str2num(event.Value);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.UIFigure);
            app.AlphaLabel = uilabel(app.Panel);
            app.AlphaLabel.Text = 'Alpha      ';
            app.AlphaEditField = uieditfield(app.Panel, 'text');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = '0.1';
            app.BetaLabel = uilabel(app.Panel);
            app.BetaLabel.Text = 'Beta        ';
            app.BetaEditField = uieditfield(app.Panel, 'text');
            app.BetaEditField.ValueChangingFcn = createCallbackFcn(app, @BetaEditFieldValueChanging, true);
            app.BetaEditField.Value = '0.1';
            app.GammaLabel = uilabel(app.Panel);
            app.GammaLabel.Text = 'Gamma   ';
            app.GammaEditField = uieditfield(app.Panel, 'text');
            app.GammaEditField.ValueChangingFcn = createCallbackFcn(app, @GammaEditFieldValueChanging, true);
            app.GammaEditField.Value = '0.1';
            app.IterationsEditFieldLabel = uilabel(app.Panel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.Panel, 'text');
            app.IterationsEditField.ValueChangingFcn = createCallbackFcn(app, @IterationsEditFieldValueChanging, true);
            app.IterationsEditField.Value = '200';
            app.NLabel = uilabel(app.Panel);
            app.NLabel.Text = 'N            ';
            app.NEditField = uieditfield(app.Panel, 'text');
            app.NEditField.ValueChangingFcn = createCallbackFcn(app, @NEditFieldValueChanging, true);
            app.NEditField.Value = '64';
            app.Panel_2 = uipanel(app.UIFigure);
            app.SnakeTypeDropDownLabel = uilabel(app.Panel_2);
            app.SnakeTypeDropDownLabel.Text = 'Snake Type';
            app.SnakeTypeDropDown = uidropdown(app.Panel_2);
            app.SnakeTypeDropDown.Items = {'Open', 'Closed'};
            app.SnakeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SnakeTypeDropDownValueChanged, true);
            app.SnakeTypeDropDown.Value = 'Open';
            app.StrartButton = uibutton(app.Panel_2, 'push');
            app.StrartButton.ButtonPushedFcn = createCallbackFcn(app, @StrartButtonPushed, true);
            app.StrartButton.Text = 'Strart';
            app.ResetButton = uibutton(app.Panel_2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SnakesLabel = uilabel(app.UIFigure);
            app.SnakesLabel.Text = 'Snakes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = snake

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end